classdef MatlabSolver_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CapacityEditField        matlab.ui.control.EditField
        CapacityEditFieldLabel   matlab.ui.control.Label
        ObjValEditField          matlab.ui.control.EditField
        ObjValEditFieldLabel     matlab.ui.control.Label
        OpenDataButton           matlab.ui.control.Button
        ResultTextArea           matlab.ui.control.TextArea
        SolveButton              matlab.ui.control.Button
        LanguageButtonGroup      matlab.ui.container.ButtonGroup
        PythonButton             matlab.ui.control.ToggleButton
        MatlabButton             matlab.ui.control.ToggleButton
        AlgorithmsButtonGroup    matlab.ui.container.ButtonGroup
        GurobiButton             matlab.ui.control.RadioButton
        VNSButton                matlab.ui.control.RadioButton
        TWOOPTButton             matlab.ui.control.RadioButton
        KNNButton                matlab.ui.control.RadioButton
        SAButton                 matlab.ui.control.RadioButton
        Greedy                   matlab.ui.control.RadioButton
        ACO                      matlab.ui.control.RadioButton
        GAButton                 matlab.ui.control.RadioButton
        MonteCarloButton         matlab.ui.control.RadioButton
        DPButton                 matlab.ui.control.RadioButton
        BruteForceButton         matlab.ui.control.RadioButton
        ProblemButtonGroup       matlab.ui.container.ButtonGroup
        KPButton                 matlab.ui.control.ToggleButton
        VRPButton                matlab.ui.control.ToggleButton
        TSPButton                matlab.ui.control.ToggleButton
        TimeLimitEditField       matlab.ui.control.EditField
        TimeLimitEditFieldLabel  matlab.ui.control.Label
        UIAxes_Status            matlab.ui.control.UIAxes
        UIAxes_Data              matlab.ui.control.UIAxes
        UIAxes_Result            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data; % Description
        dairy;
        Algorithms = {{0, 0, 0, 0, 0, 0, 0, 0, 0, 0;
                      0, 0, 0, 0, 0, 0, 0, 0, 0, 0;
                      0, 0, 0, 0, 0, 0, 0, 0, 0, 0;}, {0, 0, 0, 0, 0, 0, 0, 0, 0, 0, py.VRP_Python_Class_Gurobi_v2.VRPGurobi()}};
%         Algorithms = {py.VRP_Python_Class_Gurobi.VRPGurobi()};
        problemIndex = 2;
        algorithmsIndex = 11;
        languageIndex = 2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenDataButton
        function OpenDataButtonPushed(app, event)
            disp('Message: OpenDataButtonPushed');
            if app.VRPButton.Value == 1
                filename = uigetfile('.mat', 'Select .mat data');
                app.Data = load(filename).VRP_Data;

                cla(app.UIAxes_Data)
                hold(app.UIAxes_Data,'on')
                scatter(app.UIAxes_Data,app.Data.cx(1),app.Data.cy(1),50,'red','filled','s');
                scatter(app.UIAxes_Data,app.Data.cx(2:end),app.Data.cy(2:end),20,'blue','filled');
%                 text(app.UIAxes_Data,app.Data.xc(2:end)-0.4, app.Data.yc(2:end)+1, strcat('d: ',cellstr(num2str(app.Data.Dmd(2:end)'))),'Fontsize', 10);
                text(app.UIAxes_Data,app.Data.cx(2:end), app.Data.cy(2:end), cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14);

                app.CapacityEditField.Value = num2str(app.Data.capacity);
                app.TimeLimitEditField.Value = num2str(app.Data.timeLim);
            end

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            diary("Status_Output(MatlabSoloverPython)");
            diary on;

            VRPGurobi = app.Algorithms{2}{11};
            VRPGurobi.set_all(app.Data.n, app.Data.capacity, app.Data.demand, app.Data.cx, app.Data.cy, app.Data.timeLim);
            VRPGurobi.optimize();
            xc = double(VRPGurobi.get_xc());
            yc = double(VRPGurobi.get_yc());
            active_ci = int64(double(VRPGurobi.get_active_ci()));
            active_cj = int64(double(VRPGurobi.get_active_cj()));
            objVal = VRPGurobi.get_objVal();

            G = graph(active_ci+1,active_cj+1);
            cla(app.UIAxes_Result);
            hold(app.UIAxes_Result,'on');
            plot(app.UIAxes_Result,G,'XData',xc,'YData',yc,'NodeFontSize',5);
            scatter(app.UIAxes_Result,xc(1),yc(1),50,'red','filled','s');
            scatter(app.UIAxes_Result,xc(2:end),yc(2:end),20,'blue','filled');
            text(app.UIAxes_Result,app.Data.cx(2:end), app.Data.cy(2:end)+1, cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14); % 0.5 for a step
            app.ObjValEditField.Value = num2str(objVal);

            diary off;
            app.dairy=fileread('Status_Output(MatlabSoloverPython)');
            app.ResultTextArea.Value = app.dairy;
            delete('Status_Output(MatlabSoloverPython)');
        end

        % Value changed function: CapacityEditField
        function CapacityEditFieldValueChanged(app, event)
            app.Data.Capacity = str2double(app.CapacityEditField.Value);
        end

        % Value changed function: TimeLimitEditField
        function TimeLimitEditFieldValueChanged(app, event)
            app.Data.TimeLimit = str2double(app.TimeLimitEditField.Value);
        end

        % Selection changed function: AlgorithmsButtonGroup
        function AlgorithmsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmsButtonGroup.SelectedObject;
            if selectedButton == app.BruteForceButton
                disp(selectedButton);
            end
            
        end

        % Selection changed function: LanguageButtonGroup
        function LanguageButtonGroupSelectionChanged(app, event)
            selectedButton = app.LanguageButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.MatlabButton
                    disp('Message: MatlabButton Selected');
                    app.languageIndex = 1;
                case app.PythonButton
                    disp('Message: PythonButton Selected');
                    app.languageIndex = 2;
                otherwise
                    disp('Message: Unexpected language button selection changed');
            end
            disp(['Message: languageIndex is now: ', num2str(app.languageIndex)]);
        end

        % Selection changed function: ProblemButtonGroup
        function ProblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.TSPButton
                    disp('Message: TSPButton Selected');
                    app.problemIndex = 1;
                case app.VRPButton
                    disp('Message: VRPButton Selected');
                    app.problemIndex = 2;
                case app.KPButton
                    disp('Message: KPButton Selected');
                    app.problemIndex = 3;
                otherwise
                    disp('Message: Unexpected problem button selection changed');
            end
            disp(['Message: problemIndex is now: ', num2str(app.problemIndex)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Result = uiaxes(app.UIFigure);
            title(app.UIAxes_Result, 'Result Coordinates')
            xlabel(app.UIAxes_Result, 'X')
            ylabel(app.UIAxes_Result, 'Y / Value')
            zlabel(app.UIAxes_Result, 'Z')
            app.UIAxes_Data = uiaxes(app.UIFigure);
            title(app.UIAxes_Data, 'Input Coordinates')
            xlabel(app.UIAxes_Data, 'X')
            ylabel(app.UIAxes_Data, 'Y / Value')
            zlabel(app.UIAxes_Data, 'Z')
            app.UIAxes_Status = uiaxes(app.UIFigure);
            title(app.UIAxes_Status, 'Status')
            xlabel(app.UIAxes_Status, 'X')
            ylabel(app.UIAxes_Status, 'Y')
            zlabel(app.UIAxes_Status, 'Z')
            app.TimeLimitEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLimitEditFieldLabel.Text = 'Time Limit';
            app.TimeLimitEditField = uieditfield(app.UIFigure, 'text');
            app.TimeLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitEditFieldValueChanged, true);
            app.TimeLimitEditField.Value = '60';
            app.ProblemButtonGroup = uibuttongroup(app.UIFigure);
            app.ProblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemButtonGroupSelectionChanged, true);
            app.ProblemButtonGroup.Title = 'Problem';
            app.TSPButton = uitogglebutton(app.ProblemButtonGroup);
            app.TSPButton.Text = 'TSP';
            app.VRPButton = uitogglebutton(app.ProblemButtonGroup);
            app.VRPButton.Text = 'VRP';
            app.VRPButton.Value = true;
            app.KPButton = uitogglebutton(app.ProblemButtonGroup);
            app.KPButton.Text = 'KP';
            app.AlgorithmsButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmsButtonGroupSelectionChanged, true);
            app.AlgorithmsButtonGroup.Title = 'Algorithms';
            app.BruteForceButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.BruteForceButton.Text = 'Brute Force';
            app.BruteForceButton.Value = true;
            app.DPButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.DPButton.Text = 'DP';
            app.MonteCarloButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.MonteCarloButton.Text = 'Monte Carlo';
            app.GAButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GAButton.Text = 'GA';
            app.ACO = uiradiobutton(app.AlgorithmsButtonGroup);
            app.ACO.Text = 'ACO';
            app.Greedy = uiradiobutton(app.AlgorithmsButtonGroup);
            app.Greedy.Text = 'Greedy';
            app.SAButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.SAButton.Text = 'SA';
            app.KNNButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.TWOOPTButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.TWOOPTButton.Text = '2-OPT';
            app.VNSButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.VNSButton.Text = 'VNS';
            app.GurobiButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GurobiButton.Text = 'Gurobi';
            app.LanguageButtonGroup = uibuttongroup(app.UIFigure);
            app.LanguageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LanguageButtonGroupSelectionChanged, true);
            app.LanguageButtonGroup.Title = 'Language';
            app.MatlabButton = uitogglebutton(app.LanguageButtonGroup);
            app.MatlabButton.Text = 'Matlab';
            app.PythonButton = uitogglebutton(app.LanguageButtonGroup);
            app.PythonButton.Text = 'Python';
            app.PythonButton.Value = true;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            app.ResultTextArea.Placeholder = 'Output Messages Window';
            app.OpenDataButton = uibutton(app.UIFigure, 'push');
            app.OpenDataButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataButtonPushed, true);
            app.OpenDataButton.Text = 'Open Data (,mat)';
            app.ObjValEditFieldLabel = uilabel(app.UIFigure);
            app.ObjValEditFieldLabel.Text = 'ObjVal';
            app.ObjValEditField = uieditfield(app.UIFigure, 'text');
            app.ObjValEditField.Editable = 'off';
            app.CapacityEditFieldLabel = uilabel(app.UIFigure);
            app.CapacityEditFieldLabel.Text = 'Capacity';
            app.CapacityEditField = uieditfield(app.UIFigure, 'text');
            app.CapacityEditField.ValueChangedFcn = createCallbackFcn(app, @CapacityEditFieldValueChanged, true);
            app.CapacityEditField.Value = '100';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabSolver_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end